classdef ColorThreshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        RGBTab                        matlab.ui.container.Tab
        RGBGrid                       matlab.ui.container.GridLayout
        BlueSlider                    matlab.ui.control.RangeSlider
        BlueSliderLabel               matlab.ui.control.Label
        GreenSlider                   matlab.ui.control.RangeSlider
        GreenSliderLabel              matlab.ui.control.Label
        RedSlider                     matlab.ui.control.RangeSlider
        RedSliderLabel                matlab.ui.control.Label
        HSVTab                        matlab.ui.container.Tab
        HSVGrid                       matlab.ui.container.GridLayout
        ValueSlider                   matlab.ui.control.RangeSlider
        ValueSliderLabel              matlab.ui.control.Label
        SaturationSlider              matlab.ui.control.RangeSlider
        SaturationSliderLabel         matlab.ui.control.Label
        HueSlider                     matlab.ui.control.RangeSlider
        HueSliderLabel                matlab.ui.control.Label
        YcbcrTab                      matlab.ui.container.Tab
        YvbcrGrid                     matlab.ui.container.GridLayout
        CrSlider                      matlab.ui.control.RangeSlider
        CrSliderLabel                 matlab.ui.control.Label
        CbSlider                      matlab.ui.control.RangeSlider
        CbSliderLabel                 matlab.ui.control.Label
        YSlider                       matlab.ui.control.RangeSlider
        YSliderLabel                  matlab.ui.control.Label
        LabTab                        matlab.ui.container.Tab
        LabGrid                       matlab.ui.container.GridLayout
        bSlider                       matlab.ui.control.RangeSlider
        bSliderLabel                  matlab.ui.control.Label
        aSlider                       matlab.ui.control.RangeSlider
        aSliderLabel                  matlab.ui.control.Label
        LSlider                       matlab.ui.control.RangeSlider
        LSliderLabel                  matlab.ui.control.Label
        LoadGrid                      matlab.ui.container.GridLayout
        LoadimageButton               matlab.ui.control.Button
        GridLayout7                   matlab.ui.container.GridLayout
        VegetationcoverpercentageEditField  matlab.ui.control.NumericEditField
        VegetationcoverpercentageEditFieldLabel  matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        FinalsegmentationdisplayingvegetationproportionLabel  matlab.ui.control.Label
        Finalimage                    matlab.ui.control.Image
        GridLayout5                   matlab.ui.container.GridLayout
        BlackandwhitebinarymaskLabel  matlab.ui.control.Label
        BWimage                       matlab.ui.control.Image
        GridLayout4                   matlab.ui.container.GridLayout
        RawImageLabel                 matlab.ui.control.Label
        Rawimage                      matlab.ui.control.Image
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = public)
        I = [] % Raw image
    end

    methods (Access = private)
        function segmentImage(app, colorMode)
            if isempty(app.I)
                return
            end

            if colorMode=="RGB"

                % retrieve the sliders values
                redMin = app.RedSlider.Value(1);
                redMax = app.RedSlider.Value(2);
                greenMin = app.GreenSlider.Value(1);
                greenMax = app.GreenSlider.Value(2);
                blueMin = app.BlueSlider.Value(1);
                blueMax = app.BlueSlider.Value(2);

                % sliderBW = create a black and white mask (matrix)
                sliderBW = (app.I(:,:,1) >= redMin ) & (app.I(:,:,1) <= redMax) & ...
                    (app.I(:,:,2) >= greenMin ) & (app.I(:,:,2) <= greenMax) & ...
                    (app.I(:,:,3) >= blueMin ) & (app.I(:,:,3) <= blueMax);

            elseif colorMode=="HSV"

                hueMin = app.HueSlider.Value(1);
                hueMax = app.HueSlider.Value(2);
                saturationMin = app.SaturationSlider.Value(1);
                saturationMax = app.SaturationSlider.Value(2);
                valeurMin = app.ValueSlider.Value(1);
                valeurMax = app.ValueSlider.Value(2);

                % sliderBW = mask noir et blanc (matrice)
                sliderBW = (app.I(:,:,1) >= hueMin ) & (app.I(:,:,1) <= hueMax) & ...
                    (app.I(:,:,2) >= saturationMin ) & (app.I(:,:,2) <= saturationMax) & ...
                    (app.I(:,:,3) >= valeurMin ) & (app.I(:,:,3) <= valeurMax);

            elseif colorMode=="Ycbcr"

                yMin = app.YSlider.Value(1);
                yMax = app.YSlider.Value(2);
                cbMin = app.CbSlider.Value(1);
                cbMax = app.CbSlider.Value(2);
                crMin = app.CrSlider.Value(1);
                crMax = app.CrSlider.Value(2);

                % sliderBW = mask noir et blanc (matrice)
                sliderBW = (app.I(:,:,1) >= yMin ) & (app.I(:,:,1) <= yMax) & ...
                    (app.I(:,:,2) >= cbMin ) & (app.I(:,:,2) <= cbMax) & ...
                    (app.I(:,:,3) >= crMin ) & (app.I(:,:,3) <= crMax);

            elseif colorMode=="Lab"

                lMin = app.LSlider.Value(1);
                lMax = app.LSlider.Value(2);
                aMin = app.aSlider.Value(1);
                aMax = app.aSlider.Value(2);
                bMin = app.bSlider.Value(1);
                bMax = app.bSlider.Value(2);

                % sliderBW = mask noir et blanc (matrice)
                sliderBW = (app.I(:,:,1) >= lMin ) & (app.I(:,:,1) <= lMax) & ...
                    (app.I(:,:,2) >= aMin ) & (app.I(:,:,2) <= aMax) & ...
                    (app.I(:,:,3) >= bMin ) & (app.I(:,:,3) <= bMax);

            else

                error("Invalid color mode "+colorMode);

            end

            % converse to RGB matrix and display the black and white mask
            sliderBW3 = zeros(size(sliderBW,1),size(sliderBW,2),3);
            sliderBW3(:,:,1) = sliderBW*255;
            sliderBW3(:,:,2) = sliderBW*255;
            sliderBW3(:,:,3) = sliderBW*255;
            app.BWimage.ImageSource = sliderBW3;

            % display the final segmentation
            finalseg = app.I;
            finalseg(repmat(~sliderBW,[1 1 3])) = 0;
            app.Finalimage.ImageSource = finalseg;

            % calculate the vegetation cover percentage
            [nbrow, nbcol] = size(sliderBW);
            prctVege = sum(sum(sliderBW))/(nbrow*nbcol);
            app.VegetationcoverpercentageEditField.Value = prctVege*100;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            % Open file selection dialog
            [file,location] = uigetfile({'*.jpg;*.png;*.tif;*.gif', 'All Image Files'});

            % move the app back on the foreground
            figure(app.UIFigure);

            if isnumeric(file)
                return
            end
            app.I = imread(fullfile(location,file));

            % display raw image
            app.Rawimage.ImageSource = app.I;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.segmentImage(selectedTab.Title);
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            app.segmentImage("RGB");
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            app.segmentImage("RGB");
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            app.segmentImage("RGB");
        end

        % Value changed function: HueSlider
        function HueSliderValueChanged(app, event)
            app.segmentImage("HSV");
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            app.segmentImage("HSV");
        end

        % Value changed function: ValueSlider
        function ValueSliderValueChanged(app, event)
            app.segmentImage("HSV");
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            app.segmentImage("Ycbcr");
        end

        % Value changed function: CbSlider
        function CbSliderValueChanged(app, event)
            app.segmentImage("Ycbcr");
        end

        % Value changed function: CrSlider
        function CrSliderValueChanged(app, event)
            app.segmentImage("Ycbcr");
        end

        % Value changed function: LSlider
        function LSliderValueChanged(app, event)
            app.segmentImage("Lab");
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            app.segmentImage("Lab");
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            app.segmentImage("Lab");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ColorThreshold';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Rawimage = uiimage(app.GridLayout4);
            app.RawImageLabel = uilabel(app.GridLayout4);
            app.RawImageLabel.Text = 'Raw Image';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.BWimage = uiimage(app.GridLayout5);
            app.BlackandwhitebinarymaskLabel = uilabel(app.GridLayout5);
            app.BlackandwhitebinarymaskLabel.Text = 'Black and white binary mask';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.Finalimage = uiimage(app.GridLayout6);
            app.FinalsegmentationdisplayingvegetationproportionLabel = uilabel(app.GridLayout6);
            app.FinalsegmentationdisplayingvegetationproportionLabel.Text = 'Final segmentation displaying vegetation proportion';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.VegetationcoverpercentageEditFieldLabel = uilabel(app.GridLayout7);
            app.VegetationcoverpercentageEditFieldLabel.Text = 'Vegetation cover percentage:';
            app.VegetationcoverpercentageEditField = uieditfield(app.GridLayout7, 'numeric');
            app.VegetationcoverpercentageEditField.ValueDisplayFormat = '%11.4g%%';
            app.VegetationcoverpercentageEditField.Editable = 'off';
            app.LoadGrid = uigridlayout(app.GridLayout);
            app.LoadimageButton = uibutton(app.LoadGrid, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RGBTab = uitab(app.TabGroup);
            app.RGBTab.Title = 'RGB';
            app.RGBGrid = uigridlayout(app.RGBTab);
            app.RedSliderLabel = uilabel(app.RGBGrid);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.RGBGrid, 'range');
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.Value = [0 255];
            app.GreenSliderLabel = uilabel(app.RGBGrid);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.RGBGrid, 'range');
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.Value = [0 255];
            app.BlueSliderLabel = uilabel(app.RGBGrid);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.RGBGrid, 'range');
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.Value = [0 255];
            app.HSVTab = uitab(app.TabGroup);
            app.HSVTab.Title = 'HSV';
            app.HSVGrid = uigridlayout(app.HSVTab);
            app.HueSliderLabel = uilabel(app.HSVGrid);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.HSVGrid, 'range');
            app.HueSlider.Limits = [0 255];
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HueSliderValueChanged, true);
            app.HueSlider.Value = [0 255];
            app.SaturationSliderLabel = uilabel(app.HSVGrid);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.HSVGrid, 'range');
            app.SaturationSlider.Limits = [0 255];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.Value = [0 255];
            app.ValueSliderLabel = uilabel(app.HSVGrid);
            app.ValueSliderLabel.Text = 'Value';
            app.ValueSlider = uislider(app.HSVGrid, 'range');
            app.ValueSlider.Limits = [0 255];
            app.ValueSlider.ValueChangedFcn = createCallbackFcn(app, @ValueSliderValueChanged, true);
            app.ValueSlider.Value = [0 255];
            app.YcbcrTab = uitab(app.TabGroup);
            app.YcbcrTab.Title = 'Ycbcr';
            app.YvbcrGrid = uigridlayout(app.YcbcrTab);
            app.YSliderLabel = uilabel(app.YvbcrGrid);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.YvbcrGrid, 'range');
            app.YSlider.Limits = [0 255];
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.Value = [0 255];
            app.CbSliderLabel = uilabel(app.YvbcrGrid);
            app.CbSliderLabel.Text = 'Cb';
            app.CbSlider = uislider(app.YvbcrGrid, 'range');
            app.CbSlider.Limits = [0 255];
            app.CbSlider.ValueChangedFcn = createCallbackFcn(app, @CbSliderValueChanged, true);
            app.CbSlider.Value = [0 255];
            app.CrSliderLabel = uilabel(app.YvbcrGrid);
            app.CrSliderLabel.Text = 'Cr';
            app.CrSlider = uislider(app.YvbcrGrid, 'range');
            app.CrSlider.Limits = [0 255];
            app.CrSlider.ValueChangedFcn = createCallbackFcn(app, @CrSliderValueChanged, true);
            app.CrSlider.Value = [0 255];
            app.LabTab = uitab(app.TabGroup);
            app.LabTab.Title = 'Lab';
            app.LabGrid = uigridlayout(app.LabTab);
            app.LSliderLabel = uilabel(app.LabGrid);
            app.LSliderLabel.Text = 'L';
            app.LSlider = uislider(app.LabGrid, 'range');
            app.LSlider.ValueChangedFcn = createCallbackFcn(app, @LSliderValueChanged, true);
            app.aSliderLabel = uilabel(app.LabGrid);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.LabGrid, 'range');
            app.aSlider.Limits = [-100 100];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.Value = [-100 100];
            app.bSliderLabel = uilabel(app.LabGrid);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.LabGrid, 'range');
            app.bSlider.Limits = [-100 100];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.Value = [-100 100];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorThreshold

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end