classdef HSVapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        LoadfilesButton               matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        VegetationcoverpercentageEditField  matlab.ui.control.NumericEditField
        VegetationcoverpercentageEditFieldLabel  matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        FinalsegmentationdisplayingvegetationproportionLabel  matlab.ui.control.Label
        Finalimage                    matlab.ui.control.Image
        GridLayout4                   matlab.ui.container.GridLayout
        BlackandwhitebinarymaskLabel  matlab.ui.control.Label
        BWimage                       matlab.ui.control.Image
        GridLayout3                   matlab.ui.container.GridLayout
        RawImageLabel                 matlab.ui.control.Label
        Rawimage                      matlab.ui.control.Image
        GridLayout2                   matlab.ui.container.GridLayout
        ValueSlider                   matlab.ui.control.RangeSlider
        ValueSliderLabel              matlab.ui.control.Label
        SaturationSlider              matlab.ui.control.RangeSlider
        SaturationSliderLabel         matlab.ui.control.Label
        HueSlider                     matlab.ui.control.RangeSlider
        HueSliderLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
                I=[] % Raw image
    end
    
    methods (Access = private)
        
        function results = segmentimage(app)
            hueMin=app.HueSlider.Value(1);
            hueMax=app.HueSlider.Value(2);
            saturationMin=app.SaturationSlider.Value(1);
            saturationMax=app.SaturationSlider.Value(2);
            valeurMin=app.ValueSlider.Value(1);
            valeurMax=app.ValueSlider.Value(2);
           
            % sliderBW = mask noir et blanc (matrice)
            sliderBW = (app.I(:,:,1) >= hueMin ) & (app.I(:,:,1) <= hueMax) & ...
    (app.I(:,:,2) >= saturationMin ) & (app.I(:,:,2) <= saturationMax) & ...
    (app.I(:,:,3) >= valeurMin ) & (app.I(:,:,3) <= valeurMax);
           
            % converse to RGB matrix and display the black and white mask
            sliderBW3=zeros(size(sliderBW,1),size(sliderBW,2),3);
            sliderBW3(:,:,1)=sliderBW*255;
            sliderBW3(:,:,2)=sliderBW*255;
            sliderBW3(:,:,3)=sliderBW*255;
            app.BWimage.ImageSource=sliderBW3;  

            % display the final segmentation
            finalseg = app.I;
            finalseg(repmat(~sliderBW,[1 1 3])) = 0;
            app.Finalimage.ImageSource=finalseg;

            % calculate the vegetation cover percentage
            [nbrow,nbcol]=size(sliderBW);
            prctVege = sum(sum(sliderBW))/(nbrow*nbcol);
            app.VegetationcoverpercentageEditField.Value=prctVege*100;
      
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            % Open file selection dialog
            [file,location] = uigetfile({'*.jpg;*.png;*.tif;*.gif', 'All Image Files'});
            if isnumeric(file)
                return
            end
           RGB=imread(fullfile(location,file));
           
           % display raw image
           app.I = rgb2hsv(RGB);
           app.Rawimage.ImageSource=app.I;
        end

        % Value changed function: ValueSlider
        function ValueSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();            
        end

        % Value changed function: HueSlider
        function HueSliderValueChanged(app, event)
             if isempty(app.I)
                return
            end
            app.segmentimage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.HueSliderLabel = uilabel(app.GridLayout2);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.GridLayout2, 'range');
            app.HueSlider.Limits = [0 255];
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HueSliderValueChanged, true);
            app.HueSlider.Value = [0 255];
            app.SaturationSliderLabel = uilabel(app.GridLayout2);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.GridLayout2, 'range');
            app.SaturationSlider.Limits = [0 255];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.Value = [0 255];
            app.ValueSliderLabel = uilabel(app.GridLayout2);
            app.ValueSliderLabel.Text = 'Value';
            app.ValueSlider = uislider(app.GridLayout2, 'range');
            app.ValueSlider.Limits = [0 255];
            app.ValueSlider.ValueChangedFcn = createCallbackFcn(app, @ValueSliderValueChanged, true);
            app.ValueSlider.Value = [0 255];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Rawimage = uiimage(app.GridLayout3);
            app.RawImageLabel = uilabel(app.GridLayout3);
            app.RawImageLabel.Text = 'Raw Image';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.BWimage = uiimage(app.GridLayout4);
            app.BlackandwhitebinarymaskLabel = uilabel(app.GridLayout4);
            app.BlackandwhitebinarymaskLabel.Text = 'Black and white binary mask';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.Finalimage = uiimage(app.GridLayout5);
            app.FinalsegmentationdisplayingvegetationproportionLabel = uilabel(app.GridLayout5);
            app.FinalsegmentationdisplayingvegetationproportionLabel.Text = 'Final segmentation displaying vegetation proportion';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.VegetationcoverpercentageEditFieldLabel = uilabel(app.GridLayout6);
            app.VegetationcoverpercentageEditFieldLabel.Text = 'Vegetation cover percentage:';
            app.VegetationcoverpercentageEditField = uieditfield(app.GridLayout6, 'numeric');
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.LoadfilesButton = uibutton(app.GridLayout7, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Text = 'Load files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSVapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end