classdef Labapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        LoadfilesButton               matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        LSlider                       matlab.ui.control.RangeSlider
        aSlider                       matlab.ui.control.RangeSlider
        bSlider                       matlab.ui.control.RangeSlider
        bSliderLabel                  matlab.ui.control.Label
        aSliderLabel                  matlab.ui.control.Label
        LSliderLabel                  matlab.ui.control.Label
        GridLayout5                   matlab.ui.container.GridLayout
        VegetationcoverpercentageEditField  matlab.ui.control.NumericEditField
        VegetationcoverpercentageEditFieldLabel  matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        FinalsegmentationdisplayingvegetationproportionLabel  matlab.ui.control.Label
        Finalimage                    matlab.ui.control.Image
        GridLayout3                   matlab.ui.container.GridLayout
        BlackandwhitebinarymaskLabel  matlab.ui.control.Label
        BWimage                       matlab.ui.control.Image
        GridLayout2                   matlab.ui.container.GridLayout
        RawImageLabel                 matlab.ui.control.Label
        Rawimage                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        I=[] % Raw image
    end
    
    methods (Access = private)
        
        function results = segmentimage(app)
            lMin=app.LSlider.Value(1);
            lMax=app.LSlider.Value(2);
            aMin=app.aSlider.Value(1);
            aMax=app.aSlider.Value(2);
            bMin=app.bSlider.Value(1);
            bMax=app.bSlider.Value(2);
           
            % sliderBW = mask noir et blanc (matrice)
            sliderBW = (app.I(:,:,1) >= lMin ) & (app.I(:,:,1) <= lMax) & ...
    (app.I(:,:,2) >= aMin ) & (app.I(:,:,2) <= aMax) & ...
    (app.I(:,:,3) >= bMin ) & (app.I(:,:,3) <= bMax);
                     
            % converse to RGB matrix and display the black and white mask
            sliderBW3=zeros(size(sliderBW,1),size(sliderBW,2),3);
            sliderBW3(:,:,1)=sliderBW*255;
            sliderBW3(:,:,2)=sliderBW*255;
            sliderBW3(:,:,3)=sliderBW*255;
            app.BWimage.ImageSource=sliderBW3; 

            % display the final segmentation
            finalseg = app.I;
            finalseg(repmat(~sliderBW,[1 1 3])) = 0;
            app.Finalimage.ImageSource=finalseg;

            % calculate the vegetation cover percentage
            [nbrow,nbcol]=size(sliderBW);
            prctVege = sum(sum(sliderBW))/(nbrow*nbcol);
            app.VegetationcoverpercentageEditField.Value=prctVege*100;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            % Open file selection dialog
            [file,location] = uigetfile({'*.jpg;*.png;*.tif;*.gif', 'All Image Files'});
            if isnumeric(file)
                return
            end
           RGB=imread(fullfile(location,file));
           
           % display raw image
           app.I = rgb2lab(RGB);
            app.Rawimage.ImageSource=app.I;
        end

        % Value changed function: LSlider
        function LSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();            
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Rawimage = uiimage(app.GridLayout2);
            app.RawImageLabel = uilabel(app.GridLayout2);
            app.RawImageLabel.Text = 'Raw Image';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BWimage = uiimage(app.GridLayout3);
            app.BlackandwhitebinarymaskLabel = uilabel(app.GridLayout3);
            app.BlackandwhitebinarymaskLabel.Text = 'Black and white binary mask';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Finalimage = uiimage(app.GridLayout4);
            app.FinalsegmentationdisplayingvegetationproportionLabel = uilabel(app.GridLayout4);
            app.FinalsegmentationdisplayingvegetationproportionLabel.Text = 'Final segmentation displaying vegetation proportion';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.VegetationcoverpercentageEditFieldLabel = uilabel(app.GridLayout5);
            app.VegetationcoverpercentageEditFieldLabel.Text = 'Vegetation cover percentage:';
            app.VegetationcoverpercentageEditField = uieditfield(app.GridLayout5, 'numeric');
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.LSliderLabel = uilabel(app.GridLayout6);
            app.LSliderLabel.Text = 'L';
            app.aSliderLabel = uilabel(app.GridLayout6);
            app.aSliderLabel.Text = 'a';
            app.bSliderLabel = uilabel(app.GridLayout6);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.GridLayout6, 'range');
            app.bSlider.Limits = [-100 100];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.Value = [-100 100];
            app.aSlider = uislider(app.GridLayout6, 'range');
            app.aSlider.Limits = [-100 100];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.Value = [-100 100];
            app.LSlider = uislider(app.GridLayout6, 'range');
            app.LSlider.ValueChangedFcn = createCallbackFcn(app, @LSliderValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.LoadfilesButton = uibutton(app.GridLayout7, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Text = 'Load files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Labapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end