classdef RGBapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        LoadfilesButton               matlab.ui.control.Button
        GridLayout7                   matlab.ui.container.GridLayout
        VegetationcoverpercentageEditField  matlab.ui.control.NumericEditField
        VegetationcoverpercentageEditFieldLabel  matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        FinalsegmentationdisplayingvegetationproportionLabel  matlab.ui.control.Label
        Finalimage                    matlab.ui.control.Image
        GridLayout5                   matlab.ui.container.GridLayout
        BlackandwhitebinarymaskLabel  matlab.ui.control.Label
        BWimage                       matlab.ui.control.Image
        GridLayout4                   matlab.ui.container.GridLayout
        RawImageLabel                 matlab.ui.control.Label
        Rawimage                      matlab.ui.control.Image
        GridLayout3                   matlab.ui.container.GridLayout
        BlueSlider                    matlab.ui.control.RangeSlider
        BlueSliderLabel               matlab.ui.control.Label
        GreenSlider                   matlab.ui.control.RangeSlider
        GreenSliderLabel              matlab.ui.control.Label
        RedSlider                     matlab.ui.control.RangeSlider
        RedSliderLabel                matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property2 % Description
    end
    
    properties (Access = public)
        I=[] % Raw image
    end
    
    methods (Access = private)
        
        function results = traitement(app)
        end
          
        
        function results = segmentimage(app)
            % retrieve the sliders values
            redMin=app.RedSlider.Value(1);
            redMax=app.RedSlider.Value(2);
            greenMin=app.GreenSlider.Value(1);
            greenMax=app.GreenSlider.Value(2);
            blueMin=app.BlueSlider.Value(1);
            blueMax=app.BlueSlider.Value(2);
           
            % sliderBW = create a black and white mask (matrix)
            sliderBW = (app.I(:,:,1) >= redMin ) & (app.I(:,:,1) <= redMax) & ...
    (app.I(:,:,2) >= greenMin ) & (app.I(:,:,2) <= greenMax) & ...
    (app.I(:,:,3) >= blueMin ) & (app.I(:,:,3) <= blueMax);
            
            % converse to RGB matrix and display the black and white mask
            sliderBW3=zeros(size(sliderBW,1),size(sliderBW,2),3);
            sliderBW3(:,:,1)=sliderBW*255;
            sliderBW3(:,:,2)=sliderBW*255;
            sliderBW3(:,:,3)=sliderBW*255;
            app.BWimage.ImageSource=sliderBW3;

            % display the final segmentation
            finalseg = app.I;
            finalseg(repmat(~sliderBW,[1 1 3])) = 0;
            app.Finalimage.ImageSource=finalseg;

            % calculate the vegetation cover percentage
            [nbrow,nbcol]=size(sliderBW);
            prctVege = sum(sum(sliderBW))/(nbrow*nbcol);
            app.VegetationcoverpercentageEditField.Value=prctVege*100;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfilesButton
        function LoadfilesButtonPushed(app, event)
            % Open file selection dialog
            [file,location] = uigetfile({'*.jpg;*.png;*.tif;*.gif', 'All Image Files'});
            if isnumeric(file)
                return
            end
           app.I=imread(fullfile(location,file));
           
           % display raw image
            app.Rawimage.ImageSource=app.I;
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            if isempty(app.I), return; end
            app.segmentimage();
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            if isempty(app.I)
                return
            end
            app.segmentimage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RedSliderLabel = uilabel(app.GridLayout3);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.GridLayout3, 'range');
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.RedSlider.Value = [0 255];
            app.GreenSliderLabel = uilabel(app.GridLayout3);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.GridLayout3, 'range');
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.GreenSlider.Value = [0 255];
            app.BlueSliderLabel = uilabel(app.GridLayout3);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.GridLayout3, 'range');
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.BlueSlider.Value = [0 255];
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Rawimage = uiimage(app.GridLayout4);
            app.RawImageLabel = uilabel(app.GridLayout4);
            app.RawImageLabel.Text = 'Raw Image';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.BWimage = uiimage(app.GridLayout5);
            app.BlackandwhitebinarymaskLabel = uilabel(app.GridLayout5);
            app.BlackandwhitebinarymaskLabel.Text = 'Black and white binary mask';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.Finalimage = uiimage(app.GridLayout6);
            app.FinalsegmentationdisplayingvegetationproportionLabel = uilabel(app.GridLayout6);
            app.FinalsegmentationdisplayingvegetationproportionLabel.Text = 'Final segmentation displaying vegetation proportion';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.VegetationcoverpercentageEditFieldLabel = uilabel(app.GridLayout7);
            app.VegetationcoverpercentageEditFieldLabel.Text = 'Vegetation cover percentage:';
            app.VegetationcoverpercentageEditField = uieditfield(app.GridLayout7, 'numeric');
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.LoadfilesButton = uibutton(app.GridLayout8, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfilesButtonPushed, true);
            app.LoadfilesButton.Text = 'Load files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RGBapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end