classdef Ycbcrapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout7                   matlab.ui.container.GridLayout
        LoadfilesButton               matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        RawImageLabel                 matlab.ui.control.Label
        Rawimage                      matlab.ui.control.Image
        GridLayout5                   matlab.ui.container.GridLayout
        BlackandwhitebinarymaskLabel  matlab.ui.control.Label
        BWimage                       matlab.ui.control.Image
        GridLayout4                   matlab.ui.container.GridLayout
        FinalsegmentationdisplayingvegetationproportionLabel  matlab.ui.control.Label
        Finalimage                    matlab.ui.control.Image
        GridLayout3                   matlab.ui.container.GridLayout
        VegetationcoverpercentageEditField  matlab.ui.control.NumericEditField
        VegetationcoverpercentageEditFieldLabel  matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        CrSlider                      matlab.ui.control.RangeSlider
        CrSliderLabel                 matlab.ui.control.Label
        CbSlider                      matlab.ui.control.RangeSlider
        CbSliderLabel                 matlab.ui.control.Label
        YSlider                       matlab.ui.control.RangeSlider
        YSliderLabel                  matlab.ui.control.Label
    end

    properties (Access = public)
        I=[] % Raw image
    end
    
    methods (Access = private)
        
        function results = segmentimage(app)
            yMin=app.YSlider.Value(1);
            yMax=app.YSlider.Value(2);
            cbMin=app.CbSlider.Value(1);
            cbMax=app.CbSlider.Value(2);
            crMin=app.CrSlider.Value(1);
            crMax=app.CrSlider.Value(2);
           
            % sliderBW = mask noir et blanc (matrice)
            sliderBW = (app.I(:,:,1) >= yMin ) & (app.I(:,:,1) <= yMax) & ...
    (app.I(:,:,2) >= cbMin ) & (app.I(:,:,2) <= cbMax) & ...
    (app.I(:,:,3) >= crMin ) & (app.I(:,:,3) <= crMax);
           
            % converse to RGB matrix and display the black and white mask
            sliderBW3=zeros(size(sliderBW,1),size(sliderBW,2),3);
            sliderBW3(:,:,1)=sliderBW*255;
            sliderBW3(:,:,2)=sliderBW*255;
            sliderBW3(:,:,3)=sliderBW*255;
            app.BWimage.ImageSource=sliderBW3;  

            % display the final segmentation
            finalseg = app.I;
            finalseg(repmat(~sliderBW,[1 1 3])) = 0;
            app.Finalimage.ImageSource=finalseg;

            % calculate the vegetation cover percentage
            [nbrow,nbcol]=size(sliderBW);
            prctVege = sum(sum(sliderBW))/(nbrow*nbcol);
            app.VegetationcoverpercentageEditField.Value=prctVege*100;
        end
    
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.YSliderLabel = uilabel(app.GridLayout2);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.GridLayout2, 'range');
            app.YSlider.Limits = [0 255];
            app.YSlider.Value = [0 255];
            app.CbSliderLabel = uilabel(app.GridLayout2);
            app.CbSliderLabel.Text = 'Cb';
            app.CbSlider = uislider(app.GridLayout2, 'range');
            app.CbSlider.Limits = [0 255];
            app.CbSlider.Value = [0 255];
            app.CrSliderLabel = uilabel(app.GridLayout2);
            app.CrSliderLabel.Text = 'Cr';
            app.CrSlider = uislider(app.GridLayout2, 'range');
            app.CrSlider.Limits = [0 255];
            app.CrSlider.Value = [0 255];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.VegetationcoverpercentageEditFieldLabel = uilabel(app.GridLayout3);
            app.VegetationcoverpercentageEditFieldLabel.Text = 'Vegetation cover percentage:';
            app.VegetationcoverpercentageEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Finalimage = uiimage(app.GridLayout4);
            app.FinalsegmentationdisplayingvegetationproportionLabel = uilabel(app.GridLayout4);
            app.FinalsegmentationdisplayingvegetationproportionLabel.Text = 'Final segmentation displaying vegetation proportion';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.BWimage = uiimage(app.GridLayout5);
            app.BlackandwhitebinarymaskLabel = uilabel(app.GridLayout5);
            app.BlackandwhitebinarymaskLabel.Text = 'Black and white binary mask';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.Rawimage = uiimage(app.GridLayout6);
            app.RawImageLabel = uilabel(app.GridLayout6);
            app.RawImageLabel.Text = 'Raw Image';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.LoadfilesButton = uibutton(app.GridLayout7, 'push');
            app.LoadfilesButton.Text = 'Load files';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ycbcrapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end